classdef timeHistoryPlotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TimeHistoryPlotterUIFigure      matlab.ui.Figure
        PrintButton                     matlab.ui.control.Button
        keyboardButton                  matlab.ui.control.Button
        DataChannelsinCurrentAxisPanel  matlab.ui.container.Panel
        CurrentAxisListBox              matlab.ui.control.ListBox
        DeleteLineButton                matlab.ui.control.Button
        AddChanneltoAxisButton          matlab.ui.control.Button
        AxisListPanel                   matlab.ui.container.Panel
        RenameAxisButton                matlab.ui.control.Button
        DeleteAxisButton                matlab.ui.control.Button
        CopyAxisButton                  matlab.ui.control.Button
        AxisListListBox                 matlab.ui.control.ListBox
        NewButton                       matlab.ui.control.Button
        PlottingTemplatesPanel          matlab.ui.container.Panel
        CopyTemplateButton              matlab.ui.control.Button
        RenameTemplateButton            matlab.ui.control.Button
        DeleteTemplateButton            matlab.ui.control.Button
        NewTemplateButton               matlab.ui.control.Button
        PlottingTemplatesListBox        matlab.ui.control.ListBox
        clcButton                       matlab.ui.control.Button
        CloseallButton                  matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        DeleteSelectedButton            matlab.ui.control.Button
        LoadFileButton                  matlab.ui.control.Button
        FigurePropertiesPanel           matlab.ui.container.Panel
        TileSpaceDropDown               matlab.ui.control.DropDown
        TileSpaceDropDownLabel          matlab.ui.control.Label
        sgtitleEditField                matlab.ui.control.EditField
        sgtitleEditFieldLabel           matlab.ui.control.Label
        MinorGridCheckBox               matlab.ui.control.CheckBox
        MajorGridCheckBox               matlab.ui.control.CheckBox
        IndependentChannelDropDown      matlab.ui.control.DropDown
        IndependentChannelDropDownLabel  matlab.ui.control.Label
        AxisPropertiesPanel             matlab.ui.container.Panel
        LegendLocationDropDown          matlab.ui.control.DropDown
        LegendLocationDropDownLabel     matlab.ui.control.Label
        TightCheckBox                   matlab.ui.control.CheckBox
        LinePropertiesPanel             matlab.ui.container.Panel
        StyleDropDown                   matlab.ui.control.DropDown
        StyleDropDownLabel              matlab.ui.control.Label
        MarkerDropDown                  matlab.ui.control.DropDown
        MarkerDropDownLabel             matlab.ui.control.Label
        LineWidthDropDown               matlab.ui.control.DropDown
        LineWidthDropDownLabel          matlab.ui.control.Label
        ColorDropDown                   matlab.ui.control.DropDown
        ColorDropDownLabel              matlab.ui.control.Label
        MinCheckBox                     matlab.ui.control.CheckBox
        MaxCheckBox                     matlab.ui.control.CheckBox
        DataChannelsListBox             matlab.ui.control.ListBox
        DataChannelsListBoxLabel        matlab.ui.control.Label
        LoadedFilesListBox              matlab.ui.control.ListBox
        LoadedFilesListBoxLabel         matlab.ui.control.Label
    end

    
    properties (Access = public)
        tHData      % All time history data
        templates   % All templates
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                
            % Setup gui for use
            guiControl.onStartUp(app);


        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            dataLoader.topLevel(app);
        end

        % Value changed function: LoadedFilesListBox
        function LoadedFilesListBoxValueChanged(app, event)
            guiControl.listBoxSelectedChanged.loadedFiles(app);
        end

        % Button pushed function: clcButton
        function clcButtonPushed(app, event)
            clc
        end

        % Button pushed function: NewTemplateButton
        function NewTemplateButtonPushed(app, event)
            guiControl.makeNewTemplate(app, false);
        end

        % Drop down opening function: IndependentChannelDropDown
        function IndependentChannelDropDownOpening(app, event)
            guiControl.independentChannel_DropDownOpen(app);
        end

        % Button pushed function: AddChanneltoAxisButton
        function AddChanneltoAxisButtonPushed(app, event)
            guiControl.addCurChannelToCurrentAxis(app);
        end

        % Value changed function: CurrentAxisListBox
        function CurrentAxisListBoxValueChanged(app, event)
            guiControl.listBoxSelectedChanged.dataChannelsInCurrentAxis(app, ...
                event.PreviousValueIndex);
        end

        % Value changed function: PlottingTemplatesListBox
        function PlottingTemplatesListBoxValueChanged(app, event)
            guiControl.listBoxSelectedChanged.templates(app, event.PreviousValueIndex);
        end

        % Button pushed function: keyboardButton
        function keyboardButtonPushed(app, event)
            keyboard
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            guiControl.makeNewAxis(app);
        end

        % Value changed function: AxisListListBox
        function AxisListListBoxValueChanged(app, event)
            guiControl.listBoxSelectedChanged.axis(app, [], event.PreviousValueIndex, []);
            
        end

        % Button pushed function: PrintButton
        function PrintButtonPushed(app, event)
            guiControl.print(app.templates);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            plotting.topLevel(app);
        end

        % Button pushed function: CloseallButton
        function CloseallButtonPushed(app, event)
            close all
        end

        % Button pushed function: RenameTemplateButton
        function RenameTemplateButtonPushed(app, event)
            guiControl.renameTemplate(app);
        end

        % Button pushed function: CopyTemplateButton
        function CopyTemplateButtonPushed(app, event)
            guiControl.copyTemplate(app);
        end

        % Button pushed function: DeleteTemplateButton
        function DeleteTemplateButtonPushed(app, event)
            guiControl.deleteTemplate(app);
        end

        % Button pushed function: CopyAxisButton
        function CopyAxisButtonPushed(app, event)
            guiControl.copyAxis(app);
        end

        % Button pushed function: DeleteAxisButton
        function DeleteAxisButtonPushed(app, event)
            guiControl.deleteAxis(app);
        end

        % Button pushed function: RenameAxisButton
        function RenameAxisButtonPushed(app, event)
            guiControl.renameAxis(app);
        end

        % Button pushed function: DeleteLineButton
        function DeleteLineButtonPushed(app, event)
            guiControl.deleteLine(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TimeHistoryPlotterUIFigure = uifigure('Visible', 'off');
            app.TimeHistoryPlotterUIFigure.Name = 'Time History Plotter';
            app.LoadedFilesListBoxLabel = uilabel(app.TimeHistoryPlotterUIFigure);
            app.LoadedFilesListBoxLabel.Text = 'Loaded Files';
            app.LoadedFilesListBox = uilistbox(app.TimeHistoryPlotterUIFigure);
            app.LoadedFilesListBox.ValueChangedFcn = createCallbackFcn(app, @LoadedFilesListBoxValueChanged, true);
            app.DataChannelsListBoxLabel = uilabel(app.TimeHistoryPlotterUIFigure);
            app.DataChannelsListBoxLabel.Text = 'Data Channels';
            app.DataChannelsListBox = uilistbox(app.TimeHistoryPlotterUIFigure);
            app.LinePropertiesPanel = uipanel(app.TimeHistoryPlotterUIFigure);
            app.LinePropertiesPanel.Title = 'Line Properties';
            app.MaxCheckBox = uicheckbox(app.LinePropertiesPanel);
            app.MaxCheckBox.Text = 'Max';
            app.MinCheckBox = uicheckbox(app.LinePropertiesPanel);
            app.MinCheckBox.Text = 'Min';
            app.ColorDropDownLabel = uilabel(app.LinePropertiesPanel);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.LinePropertiesPanel);
            app.ColorDropDown.Items = {'automatic', 'black', 'blue', 'orange', 'yellow', 'purple', 'green', 'light blue', 'red', 'magenta'};
            app.ColorDropDown.Value = 'automatic';
            app.LineWidthDropDownLabel = uilabel(app.LinePropertiesPanel);
            app.LineWidthDropDownLabel.Text = 'Line Width';
            app.LineWidthDropDown = uidropdown(app.LinePropertiesPanel);
            app.LineWidthDropDown.Items = {'0.1', '0.25', '0.5', '0.75', '1.0', '1.5', '2.0', '2.5', '3.0', '4.0', '5.0', '6.0'};
            app.LineWidthDropDown.Value = '0.5';
            app.MarkerDropDownLabel = uilabel(app.LinePropertiesPanel);
            app.MarkerDropDownLabel.Text = 'Marker';
            app.MarkerDropDown = uidropdown(app.LinePropertiesPanel);
            app.MarkerDropDown.Items = {'none', 'o', '+', '*', '.', 'x', '_', '|', 'square', 'diamond', '^', 'v', '>', '<', 'pentagram', 'hexagram'};
            app.MarkerDropDown.Value = 'none';
            app.StyleDropDownLabel = uilabel(app.LinePropertiesPanel);
            app.StyleDropDownLabel.Text = 'Style';
            app.StyleDropDown = uidropdown(app.LinePropertiesPanel);
            app.StyleDropDown.Items = {'-', ':', '-.', '--', 'none'};
            app.StyleDropDown.Value = '-';
            app.AxisPropertiesPanel = uipanel(app.TimeHistoryPlotterUIFigure);
            app.AxisPropertiesPanel.Title = 'Axis Properties';
            app.TightCheckBox = uicheckbox(app.AxisPropertiesPanel);
            app.TightCheckBox.Text = 'Tight';
            app.LegendLocationDropDownLabel = uilabel(app.AxisPropertiesPanel);
            app.LegendLocationDropDownLabel.Text = 'Legend Location';
            app.LegendLocationDropDown = uidropdown(app.AxisPropertiesPanel);
            app.LegendLocationDropDown.Items = {'best', 'northeast', 'northwest', 'north', 'south', 'east', 'west', 'southeast', 'southwest', 'northoutside', 'southoutside', 'eastoutside', 'westoutside', 'northeastoutside', 'northwestoutside', 'southeastoutside', 'southwestoutside', 'bestoutside', 'none'};
            app.LegendLocationDropDown.Value = 'best';
            app.FigurePropertiesPanel = uipanel(app.TimeHistoryPlotterUIFigure);
            app.FigurePropertiesPanel.Title = 'Figure Properties';
            app.IndependentChannelDropDownLabel = uilabel(app.FigurePropertiesPanel);
            app.IndependentChannelDropDownLabel.Text = 'Independent Channel';
            app.IndependentChannelDropDown = uidropdown(app.FigurePropertiesPanel);
            app.IndependentChannelDropDown.DropDownOpeningFcn = createCallbackFcn(app, @IndependentChannelDropDownOpening, true);
            app.MajorGridCheckBox = uicheckbox(app.FigurePropertiesPanel);
            app.MajorGridCheckBox.Text = 'Major Grid';
            app.MinorGridCheckBox = uicheckbox(app.FigurePropertiesPanel);
            app.MinorGridCheckBox.Text = 'Minor Grid';
            app.sgtitleEditFieldLabel = uilabel(app.FigurePropertiesPanel);
            app.sgtitleEditFieldLabel.Text = 'sgtitle';
            app.sgtitleEditField = uieditfield(app.FigurePropertiesPanel, 'text');
            app.TileSpaceDropDownLabel = uilabel(app.FigurePropertiesPanel);
            app.TileSpaceDropDownLabel.Text = 'Tile Space';
            app.TileSpaceDropDown = uidropdown(app.FigurePropertiesPanel);
            app.TileSpaceDropDown.Items = {'loose', 'compact', 'tight', 'none'};
            app.TileSpaceDropDown.Value = 'loose';
            app.LoadFileButton = uibutton(app.TimeHistoryPlotterUIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.DeleteSelectedButton = uibutton(app.TimeHistoryPlotterUIFigure, 'push');
            app.DeleteSelectedButton.Enable = 'off';
            app.DeleteSelectedButton.Text = 'Delete Selected';
            app.PlotButton = uibutton(app.TimeHistoryPlotterUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.CloseallButton = uibutton(app.TimeHistoryPlotterUIFigure, 'push');
            app.CloseallButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallButtonPushed, true);
            app.CloseallButton.Text = 'Close all';
            app.clcButton = uibutton(app.TimeHistoryPlotterUIFigure, 'push');
            app.clcButton.ButtonPushedFcn = createCallbackFcn(app, @clcButtonPushed, true);
            app.clcButton.Text = 'clc';
            app.PlottingTemplatesPanel = uipanel(app.TimeHistoryPlotterUIFigure);
            app.PlottingTemplatesPanel.Title = 'Plotting Templates';
            app.PlottingTemplatesListBox = uilistbox(app.PlottingTemplatesPanel);
            app.PlottingTemplatesListBox.ValueChangedFcn = createCallbackFcn(app, @PlottingTemplatesListBoxValueChanged, true);
            app.NewTemplateButton = uibutton(app.PlottingTemplatesPanel, 'push');
            app.NewTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @NewTemplateButtonPushed, true);
            app.NewTemplateButton.Text = 'New';
            app.DeleteTemplateButton = uibutton(app.PlottingTemplatesPanel, 'push');
            app.DeleteTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteTemplateButtonPushed, true);
            app.DeleteTemplateButton.Text = 'Delete';
            app.RenameTemplateButton = uibutton(app.PlottingTemplatesPanel, 'push');
            app.RenameTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @RenameTemplateButtonPushed, true);
            app.RenameTemplateButton.Text = 'Rename';
            app.CopyTemplateButton = uibutton(app.PlottingTemplatesPanel, 'push');
            app.CopyTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @CopyTemplateButtonPushed, true);
            app.CopyTemplateButton.Text = 'Copy';
            app.AxisListPanel = uipanel(app.TimeHistoryPlotterUIFigure);
            app.AxisListPanel.Title = 'Axis List';
            app.NewButton = uibutton(app.AxisListPanel, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New';
            app.AxisListListBox = uilistbox(app.AxisListPanel);
            app.AxisListListBox.ValueChangedFcn = createCallbackFcn(app, @AxisListListBoxValueChanged, true);
            app.CopyAxisButton = uibutton(app.AxisListPanel, 'push');
            app.CopyAxisButton.ButtonPushedFcn = createCallbackFcn(app, @CopyAxisButtonPushed, true);
            app.CopyAxisButton.Text = 'Copy';
            app.DeleteAxisButton = uibutton(app.AxisListPanel, 'push');
            app.DeleteAxisButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAxisButtonPushed, true);
            app.DeleteAxisButton.Text = 'Delete';
            app.RenameAxisButton = uibutton(app.AxisListPanel, 'push');
            app.RenameAxisButton.ButtonPushedFcn = createCallbackFcn(app, @RenameAxisButtonPushed, true);
            app.RenameAxisButton.Text = 'Rename';
            app.DataChannelsinCurrentAxisPanel = uipanel(app.TimeHistoryPlotterUIFigure);
            app.DataChannelsinCurrentAxisPanel.Title = 'Data Channels in Current Axis';
            app.AddChanneltoAxisButton = uibutton(app.DataChannelsinCurrentAxisPanel, 'push');
            app.AddChanneltoAxisButton.ButtonPushedFcn = createCallbackFcn(app, @AddChanneltoAxisButtonPushed, true);
            app.AddChanneltoAxisButton.Text = 'Add Channel to Axis';
            app.DeleteLineButton = uibutton(app.DataChannelsinCurrentAxisPanel, 'push');
            app.DeleteLineButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteLineButtonPushed, true);
            app.DeleteLineButton.Text = 'Delete';
            app.CurrentAxisListBox = uilistbox(app.DataChannelsinCurrentAxisPanel);
            app.CurrentAxisListBox.ValueChangedFcn = createCallbackFcn(app, @CurrentAxisListBoxValueChanged, true);
            app.keyboardButton = uibutton(app.TimeHistoryPlotterUIFigure, 'push');
            app.keyboardButton.ButtonPushedFcn = createCallbackFcn(app, @keyboardButtonPushed, true);
            app.keyboardButton.Text = 'keyboard';
            app.PrintButton = uibutton(app.TimeHistoryPlotterUIFigure, 'push');
            app.PrintButton.ButtonPushedFcn = createCallbackFcn(app, @PrintButtonPushed, true);
            app.PrintButton.Text = 'Print';
            % Show the figure after all components are created
            app.TimeHistoryPlotterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = timeHistoryPlotter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TimeHistoryPlotterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TimeHistoryPlotterUIFigure)
        end
    end
end